classdef MaquinaMNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MtodosNumricospararesoluodeSistemasdeEDUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ExportarGrficoparaMenu       matlab.ui.container.Menu
        ExcelMenu                    matlab.ui.container.Menu
        ImagemMenu                   matlab.ui.container.Menu
        AbrirRelatrioMenu            matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OcultarLegendaCheckBox       matlab.ui.control.CheckBox
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ddSDinamico                  matlab.ui.control.DropDown
        SistemadinmicoDropDownLabel  matlab.ui.control.Label
        bAtualizar_2                 matlab.ui.control.Button
        bAtualizar                   matlab.ui.control.Button
        bgMetodos                    matlab.ui.container.ButtonGroup
        rbTodos                      matlab.ui.control.RadioButton
        rbRK4                        matlab.ui.control.RadioButton
        rbRK2                        matlab.ui.control.RadioButton
        rbEulerM                     matlab.ui.control.RadioButton
        rbEuler                      matlab.ui.control.RadioButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_5            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        gtuvLabel                    matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eN                           matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UITable                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            app.OcultarLegendaCheckBox.Value=0;
            app.GrelhaCheckBox.Value=1;
            cla(app.UIAxes)
            syms u(t) v(t)
            strF = app.eF.Value;
            f = @(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g = @(t,u,v) eval(vectorize(strG));

            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;
            drop = app.ddSDinamico.Value;

            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.rbEuler,...
                app.rbEulerM,...
                app.rbRK2,...
                app.rbRK4,...
                app.rbTodos]==escolhabgMetodos);

            erroFuncao = MException("Matlab:bAtualizar:badF",...
                "Introduza uma função em t, u e v" );

            erroB = MException("Matlab:bAtualizar:badB",...
                "Introduza um b > a" );
            %outras

            try
                try
                    z = g(t,u,v);
                    app.eF.BackgroundColor = "w";

                catch ME
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncao)
                end

                if ~(isreal(b) && b > a)
                    app.eB.BackgroundColor = "r";
                    throw(erroB)
                else
                    app.eB.BackgroundColor = "w";

                end

                eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                if(drop == "2")
                    eqns = [diff(u,t)==v,diff(v,t)==-10*v-25*u];
                end

                if(drop == "4")
                    eqns = [diff(u,t)==v,diff(v,t)==5*v-4*u+2*exp(-t/4)];
                end
                if(drop == "6")
                    eqns = [diff(u,t)==v,diff(v,t)==-20*v-96*u];
                end


                ci = [u(0)==u0,v(0)==v0];
                [sExataU,~] = dsolve(eqns,ci);

                p =@(t) eval(vectorize(char(sExataU)));

                [t,uEulerM,~] = NEulerMelhoradoSED(f,g,a,b,n,u0,v0);
                [t,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);

                uExata = p(t);

                h = (b-a)/n;
                t = a:h:b;

                erroEuler = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);


                switch rbMetodoSelecionado
                    case 1
                        if(drop == "3" || drop =='5' || drop == '4' || drop=='7')  %se o pendulo, modelo vibratorio mecanico ou circuitos eletricos for selecionado retira a exata.
                            plot(app.UIAxes,t,uEuler,"-k")
                            matrizResultados = [t.', uEuler.'];
                            legendaGrafico = "Euler";
                            NomeColunasTabela = ["t","Euler"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uEuler,"-k")
                            matrizResultados = [t.', uExata.', uEuler.',erroEuler.'];
                            legendaGrafico = ["Exata","Euler"];
                            NomeColunasTabela = ["t","Exata","Euler","ErroEuler"];
                        end
                    case 2
                        if(drop == "3" || drop =='5' || drop == '4' || drop=='7')
                            plot(app.UIAxes,t,uEulerM,"-og")
                            matrizResultados = [t.', uEulerM.'];
                            legendaGrafico = "Euler Melhorado";
                            NomeColunasTabela = ["t","Euler Melhorado"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uEulerM,"-og")
                            matrizResultados = [t.', uExata.', uEulerM.',erroEulerM.'];
                            legendaGrafico = ["Exata","Euler Melhorado"];
                            NomeColunasTabela = ["t","Exata","EulerM","Erro EulerM"];
                        end
                    case 3
                        if(drop == "3" || drop =='5' || drop == '4' || drop=='7')
                            plot(app.UIAxes,t,uRK2,"-+b")
                            matrizResultados = [t.', uRK2.'];
                            legendaGrafico = "RK2";
                            NomeColunasTabela = ["t","RK2"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uRK2,"-+b")
                            matrizResultados = [t.', uExata.', uRK2.',erroRK2.'];
                            legendaGrafico = ["Exata","RK2"];
                            NomeColunasTabela = ["t","Exata","RK2","ErroRK2"];
                        end
                    case 4
                        if(drop == "3" || drop =='5' || drop == '4' || drop=='7')
                            plot(app.UIAxes,t,uRK4,"-y")
                            matrizResultados = [t.', uRK4.'];
                            legendaGrafico = "RK4";
                            NomeColunasTabela = ["t","RK4"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uRK4,"-y")
                            matrizResultados = [t.', uExata.', uRK4.',erroRK4.'];
                            legendaGrafico = ["Exata","RK4"];
                            NomeColunasTabela = ["t","Exata","RK4","ErroRK4"];
                        end
                    case 5
                        if(drop == "3" || drop =='5' || drop == '4' || drop=='7')
                            plot(app.UIAxes,t,uEuler,"-k")
                            plot(app.UIAxes,t,uRK2,"-+b")
                            plot(app.UIAxes,t,uRK4,"-y")
                            plot(app.UIAxes,t,uEulerM,"-og")

                            matrizResultados = [t.', uEuler.'...
                                uRK2.',uRK4.',uEulerM.'];
                            legendaGrafico = ["Euler","RK2", "RK4","Euler Melhorado"];
                            NomeColunasTabela = ["t","Euler", "RK2","RK4","Euler Melhorado"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uEuler,"-k")
                            plot(app.UIAxes,t,uRK2,"-+b")
                            plot(app.UIAxes,t,uRK4,"-y")
                            plot(app.UIAxes,t,uEulerM,"-og")


                            matrizResultados = [t.', uExata.', uEuler.'...
                                uRK2.',uRK4.',uEulerM.',erroEuler.',erroRK2.',erroRK4.',erroEulerM.'];
                            legendaGrafico = ["Exata","Euler","RK2", "RK4","Euler Melhorado"];
                            NomeColunasTabela = ["t","Exata","Euler", "RK2","RK4","Euler Melhorado",...
                                "ErroEuler","erroRK2","erroRK4","Erro Euler Melhorado"];
                        end
                end

                grid(app.UIAxes,"on")
                legend(app.UIAxes,legendaGrafico)

                app.UITable.Data = array2table(matrizResultados);
                app.UITable.ColumnName = NomeColunasTabela;

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.MtodosNumricospararesoluodeSistemasdeEDUIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autor
        end

        % Value changed function: ddSDinamico
        function ddSDinamicoValueChanged(app, event)
            value = app.ddSDinamico.Value;

            switch value
                case "1" %mola massa s/amortecimento
                    strG = "-16*u";
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
                case "2" %mola massa com amortecimento
                    strG = "-10*v-25*u";
                    a = 0; b = 2; n = 100;
                    u0 = 0; v0 = -4;
                case "3" %pendulo
                    strG = "-0.3*v-sin(u)";
                    a = 0; b = 15; n = 100;
                    u0 = pi/2; v0 = 0;
                case "4" %Circuitos Elétricos
                    strG =  "-5*v-4*u+2*exp(-t/4)";
                    a = 0; b = 10; n = 100;
                    u0 = 0; v0 = 0;
                case "5"%Modelo Vibratório Mecanico
                    strG =  "-2*v-2*u+4*cos(t)+2*sin(t)";
                    a = 0; b = 15; n = 100;
                    u0 = 0; v0 = 3;
                case "6"%Sistema de suspensao de motociclo
                    strG =  "-20*v-96*u";
                    a = 0; b = 5; n = 100;
                    u0 = 0.3333; v0 = 10;

                case "7"%outro
                    strG =  "0";
                    a = 0; b = 0; n = 0;
                    u0 = 0; v0 = 0;
                    cla(app.UIAxes, 'reset'); % Limpa o gráfico
                    app.UITable.Data = {};%limpa a tabela
                    warndlg('Introduza o problema em g(t,u,v)','Atenção');

            end

            app.eG.Value = strG;
            app.eA.Value = a;
            app.eB.Value = b;
            app.eN.Value = n;
            app.eU0.Value = u0;
            app.eV0.Value = v0;
            if ~strcmp(value, "7") % apenas chamar a função se o valor não for "7"
                bAtualizarButtonPushed(app, event);
            end

        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            %botao reset
            % Mostra uma caixa de diálogo com uma mensagem e botões de opção

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'
                    app.ddSDinamico.Value = "1"; %colocar o dropdown default
                    app.eF.Value = 'v';%apagar a funcao (string)
                    app.eG.Value = '-16*u';
                    app.eN.Value = 100; %valor numerico
                    app.eA.Value = 0;
                    app.eU0.Value = 9;
                    app.eB.Value = 4;
                    app.eV0.Value = 0;

                    app.bgMetodos.SelectedObject = app.rbEuler; %botao euler a ser inicializado
                    bAtualizarButtonPushed(app, event); %chamar a funcao inicializada

                case 'Não'
                    % Não faz o reset
            end
        end

        % Menu selected function: AbrirRelatrioMenu
        function AbrirRelatrioMenuSelected(app, event)
            open('Fabio_Oliveira-2022145902_Carlos_Silva-2022127048_Bruno_Martins-2022147149.pdf');
        end

        % Menu selected function: ExportarGrficoparaMenu
        function ExportarGrficoparaMenuSelected(app, event)

        end

        % Menu selected function: ImagemMenu
        function ImagemMenuSelected(app, event)
            % Receber o nome do ficheiro
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);

            % Tira uma print ao grafico
            exportgraphics(app.UIAxes, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Menu selected function: ExcelMenu
        function ExcelMenuSelected(app, event)

            % Obter os dados da tabela que deseja exportar
            dados = app.UITable.Data;
            colunas = app.UITable.ColumnName;

            % Verificar se há tabelas aninhadas e convertê-las em células
            dados = convertNestedTablesToCells(dados);

            % Criar uma tabela a partir dos dados e nomes das colunas
            tabela = array2table(dados, 'VariableNames', colunas);

            % Especificar o nome do arquivo Excel de destino
            nomeFicheiro = 'dados.xlsx';
            % Se o excel existir é apagado primeiro (dados)
            if isfile("dados.xlsx")
                delete("dados.xlsx");
            end
            % Guardar a tabela no ficheiro Excel
            writetable(tabela, nomeFicheiro);

            % Mostrar uma mensagem de confirmação
            msgbox('Tabela exportada com sucesso para o Excel.', 'Exportação Concluída');

            % Função para converter tabelas alinhadas em células
            function data = convertNestedTablesToCells(data)
                if istable(data)
                    data = table2cell(data);
                elseif iscell(data)
                    for i = 1:numel(data)
                        data{i} = convertNestedTablesToCells(data{i});
                    end
                end
            end
            winopen(nomeFicheiro);


        end

        % Callback function
        function DarkModeMenuSelected(app, event)
         
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on");
            else
                grid(app.UIAxes,"off")
            end
        end

        % Value changed function: OcultarLegendaCheckBox
        function OcultarLegendaCheckBoxValueChanged(app, event)
            value = app.OcultarLegendaCheckBox.Value;
            if value
                legend(app.UIAxes,"off");
            else
                legend(app.UIAxes);
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MtodosNumricospararesoluodeSistemasdeEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MtodosNumricospararesoluodeSistemasdeEDUIFigure = uifigure('Visible', 'off');
            app.MtodosNumricospararesoluodeSistemasdeEDUIFigure.Name = 'Métodos Numéricos para resolução de Sistemas de ED';
            app.MtodosNumricospararesoluodeSistemasdeEDUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.MtodosNumricospararesoluodeSistemasdeEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.MtodosNumricospararesoluodeSistemasdeEDUIFigure);
            app.FileMenu.Text = 'File';
            app.ExportarGrficoparaMenu = uimenu(app.FileMenu);
            app.ExportarGrficoparaMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarGrficoparaMenuSelected, true);
            app.ExportarGrficoparaMenu.Text = 'Exportar Gráfico para ...';
            app.ExcelMenu = uimenu(app.ExportarGrficoparaMenu);
            app.ExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.ExcelMenu.Text = 'Excel';
            app.ImagemMenu = uimenu(app.ExportarGrficoparaMenu);
            app.ImagemMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagemMenuSelected, true);
            app.ImagemMenu.Text = 'Imagem';
            app.AbrirRelatrioMenu = uimenu(app.FileMenu);
            app.AbrirRelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirRelatrioMenuSelected, true);
            app.AbrirRelatrioMenu.Text = 'Abrir Relatório';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.MtodosNumricospararesoluodeSistemasdeEDUIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MtodosNumricospararesoluodeSistemasdeEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 4;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'n';
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 100;
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.gtuvLabel = uilabel(app.DadosdoPVIPanel);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_5 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_5.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bAtualizar_2 = uibutton(app.LeftPanel, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Reset';
            app.SistemadinmicoDropDownLabel = uilabel(app.LeftPanel);
            app.SistemadinmicoDropDownLabel.Text = 'Sistema dinâmico';
            app.ddSDinamico = uidropdown(app.LeftPanel);
            app.ddSDinamico.Items = {'Mola massa sem amortecimento', 'Mola Massa com amortecimento', 'Pêndulo', 'Circuitos Elétricos', 'Modelo Vibratório Mecanico', 'Sistema de Suspensão de Motociclo', 'Outro'};
            app.ddSDinamico.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.ddSDinamico.ValueChangedFcn = createCallbackFcn(app, @ddSDinamicoValueChanged, true);
            app.ddSDinamico.Value = '1';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.OcultarLegendaCheckBox = uicheckbox(app.LeftPanel);
            app.OcultarLegendaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OcultarLegendaCheckBoxValueChanged, true);
            app.OcultarLegendaCheckBox.Text = 'Ocultar Legenda';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.MtodosNumricospararesoluodeSistemasdeEDUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MtodosNumricospararesoluodeSistemasdeEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaMNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MtodosNumricospararesoluodeSistemasdeEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MtodosNumricospararesoluodeSistemasdeEDUIFigure)
        end
    end
end