classdef appintegracao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntegraaoNumericaUIFigure  matlab.ui.Figure
        ResultadodosMtodosdeIntegraaoLabel  matlab.ui.control.Label
        IntegralLabel              matlab.ui.control.Label
        bgMetodos                  matlab.ui.container.ButtonGroup
        ResetButton                matlab.ui.control.Button
        ExecutarButton             matlab.ui.control.Button
        TodosButton                matlab.ui.control.RadioButton
        RegraSimpsonButton         matlab.ui.control.RadioButton
        RegraTrapziosButton        matlab.ui.control.RadioButton
        Panel                      matlab.ui.container.Panel
        eN                         matlab.ui.control.NumericEditField
        nLabel                     matlab.ui.control.Label
        eF                         matlab.ui.control.EditField
        ExpresoLabel               matlab.ui.control.Label
        eA                         matlab.ui.control.NumericEditField
        aEditField_2Label          matlab.ui.control.Label
        eB                         matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        UIIntegral                 matlab.ui.control.UIAxes
        UIRegras                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)

            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; n = app.eN.Value; b = app.eB.Value;
            cla(app.UIRegras);
            cla(app.UIIntegral);


            int= integral(f,a,b);
            % Calcular a integral usando a Regra dos Trapézios
            T = Trapezios(f, a, b, n);

            % Calcular a integral usando a Regra de Simpson
            S = Simpson(f, a, b, n);

            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.RegraTrapziosButton,...
                app.RegraSimpsonButton,...
                app.TodosButton]==escolhabgMetodos);


            formaLatex = sprintf('\\int_{%i}^{%i} %s =%s',a,b, strF, num2str(int));
            text(app.UIIntegral, 0.1, 0.3, formaLatex, 'Units', 'normalized', 'FontSize', 15);


            switch rbMetodoSelecionado
                case 1

                    result_str = sprintf('Regra dos Trapézios: %f\n',T);

                    text(app.UIRegras, 0.1, 0.3, result_str, 'Units', 'normalized', 'FontSize', 14);

                case 2
                    result_str = sprintf('Regra de Simpson: %f\n',S);

                    text(app.UIRegras, 0.1, 0.3, result_str, 'Units', 'normalized', 'FontSize', 14);

                case 3
                    result_str = sprintf('Regra dos Trapézios: %f\nRegra de Simpson: %f', T, S);

                    text(app.UIRegras, 0.1, 0.3, result_str, 'Units', 'normalized', 'FontSize', 14);
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %botao reset
            % Mostra uma caixa de diálogo com uma mensagem e botões de opção

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'

                    app.eF.Value = '(x+3)*exp(x/2)';
                    app.eA.Value = 0;
                    app.eN.Value = 20;
                    app.eB.Value = 10 ;


                    ExecutarButtonPushed(app, event);  %chamar a funcao inicializada

                case 'Não'
                    % Não faz o reset
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IntegraaoNumericaUIFigure = uifigure('Visible', 'off');
            app.IntegraaoNumericaUIFigure.Name = 'Integraçao Numerica';
            app.IntegraaoNumericaUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIRegras = uiaxes(app.IntegraaoNumericaUIFigure);
            app.UIRegras.XTick = [];
            app.UIRegras.YTick = [];
            app.UIRegras.ZTick = [];
            app.UIIntegral = uiaxes(app.IntegraaoNumericaUIFigure);
            app.UIIntegral.XTick = [];
            app.UIIntegral.YTick = [];
            app.UIIntegral.ZTick = [];
            app.Panel = uipanel(app.IntegraaoNumericaUIFigure);
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 10;
            app.aEditField_2Label = uilabel(app.Panel);
            app.aEditField_2Label.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.ExpresoLabel = uilabel(app.Panel);
            app.ExpresoLabel.Text = 'Expresão';
            app.eF = uieditfield(app.Panel, 'text');
            app.eF.Value = '(x+3)*exp(x/2)';
            app.nLabel = uilabel(app.Panel);
            app.nLabel.Text = 'n';
            app.eN = uieditfield(app.Panel, 'numeric');
            app.eN.Value = 20;
            app.bgMetodos = uibuttongroup(app.IntegraaoNumericaUIFigure);
            app.bgMetodos.Title = 'Métodos de Integracao Numérica';
            app.RegraTrapziosButton = uiradiobutton(app.bgMetodos);
            app.RegraTrapziosButton.Text = 'Regra Trapézios';
            app.RegraTrapziosButton.Value = true;
            app.RegraSimpsonButton = uiradiobutton(app.bgMetodos);
            app.RegraSimpsonButton.Text = 'Regra Simpson';
            app.TodosButton = uiradiobutton(app.bgMetodos);
            app.TodosButton.Text = 'Todos';
            app.ExecutarButton = uibutton(app.bgMetodos, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.bgMetodos, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.IntegralLabel = uilabel(app.IntegraaoNumericaUIFigure);
            app.IntegralLabel.Text = 'Integral';
            app.ResultadodosMtodosdeIntegraaoLabel = uilabel(app.IntegraaoNumericaUIFigure);
            app.ResultadodosMtodosdeIntegraaoLabel.Text = 'Resultado dos Métodos de Integraçao';
            % Show the figure after all components are created
            app.IntegraaoNumericaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appintegracao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IntegraaoNumericaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntegraaoNumericaUIFigure)
        end
    end
end